classdef AnimateTrial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        StopCheckBox                    matlab.ui.control.CheckBox
        T_INTVMEAN                      matlab.ui.control.NumericEditField
        T_INTVMEANEditFieldLabel        matlab.ui.control.Label
        WAVEMAKER                       matlab.ui.control.EditField
        WAVEMAKEREditFieldLabel         matlab.ui.control.Label
        DIFFUSIONSPONGE                 matlab.ui.control.EditField
        DIFFUSIONSPONGEEditField_2Label  matlab.ui.control.Label
        Cbrk2                           matlab.ui.control.NumericEditField
        Cbrk2EditFieldLabel             matlab.ui.control.Label
        Cbrk1                           matlab.ui.control.NumericEditField
        Cbrk1EditFieldLabel             matlab.ui.control.Label
        Cd                              matlab.ui.control.NumericEditField
        CdEditFieldLabel                matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        SpongeDimensionsLabel           matlab.ui.control.Label
        Sponge_N                        matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        Sponge_S                        matlab.ui.control.NumericEditField
        SEditFieldLabel                 matlab.ui.control.Label
        Sponge_E                        matlab.ui.control.NumericEditField
        EEditFieldLabel                 matlab.ui.control.Label
        Sponge_W                        matlab.ui.control.NumericEditField
        WEditFieldLabel                 matlab.ui.control.Label
        Sponge_Csp                      matlab.ui.control.NumericEditField
        CspEditFieldLabel               matlab.ui.control.Label
        CDSponge                        matlab.ui.control.NumericEditField
        CDSpongeEditFieldLabel          matlab.ui.control.Label
        DIRECTSPONGE                    matlab.ui.control.EditField
        DIRECTSPONGEEditFieldLabel      matlab.ui.control.Label
        FRICTIONSPONGE                  matlab.ui.control.EditField
        FRICTIONSPONGEEditFieldLabel    matlab.ui.control.Label
        VISCOSITYBREAKING               matlab.ui.control.EditField
        VISCOSITYBREAKINGEditFieldLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        DEP_WK                          matlab.ui.control.NumericEditField
        DEP_WKEditFieldLabel            matlab.ui.control.Label
        Theta_WK                        matlab.ui.control.NumericEditField
        Theta_WKEditFieldLabel          matlab.ui.control.Label
        Xc_WK                           matlab.ui.control.NumericEditField
        Xc_WKEditFieldLabel             matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MinDepth                        matlab.ui.control.NumericEditField
        MinDepthEditFieldLabel          matlab.ui.control.Label
        FroudeCap                       matlab.ui.control.NumericEditField
        FroudeCapEditFieldLabel         matlab.ui.control.Label
        SCREEN_INTV                     matlab.ui.control.NumericEditField
        SCREEN_INTVEditFieldLabel       matlab.ui.control.Label
        PLOT_INTV                       matlab.ui.control.NumericEditField
        PLOT_INTVEditFieldLabel         matlab.ui.control.Label
        CFL                             matlab.ui.control.NumericEditField
        CFLEditFieldLabel               matlab.ui.control.Label
        TOTAL_TIME                      matlab.ui.control.NumericEditField
        TOTAL_TIMEEditFieldLabel        matlab.ui.control.Label
        PY                              matlab.ui.control.NumericEditField
        PYEditFieldLabel                matlab.ui.control.Label
        PX                              matlab.ui.control.NumericEditField
        PXEditFieldLabel                matlab.ui.control.Label
        DY                              matlab.ui.control.NumericEditField
        DYEditFieldLabel                matlab.ui.control.Label
        DX                              matlab.ui.control.NumericEditField
        DXEditFieldLabel                matlab.ui.control.Label
        Nglob                           matlab.ui.control.NumericEditField
        NglobEditFieldLabel             matlab.ui.control.Label
        Mglob                           matlab.ui.control.NumericEditField
        MglobEditFieldLabel             matlab.ui.control.Label
        FunwaveConstantParametersLabel  matlab.ui.control.Label
        FolderSelectedEditField         matlab.ui.control.EditField
        FolderSelectedEditFieldLabel    matlab.ui.control.Label
        LoadinDataLabel                 matlab.ui.control.Label
        LoadDataSelectFolderButton      matlab.ui.control.Button
        FunwaveVariableParametersLabel  matlab.ui.control.Label
        TPERIODEditField                matlab.ui.control.NumericEditField
        TPERIODEditFieldLabel           matlab.ui.control.Label
        AMP_WKEditField                 matlab.ui.control.NumericEditField
        AMP_WKEditFieldLabel            matlab.ui.control.Label
        SLPEditField                    matlab.ui.control.NumericEditField
        SLPEditFieldLabel               matlab.ui.control.Label
        AnimateButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        %%% Raw Data
            NPY 
            sumvars
            sumconst
        
        %%% Default Variables
            SLP=0.05    
            AMPWK=1
            TPERIOD=9

        %%% Index of closest matching trial
            ind

        %%% Animation Status
            stop = false 
            
    end
    
    methods (Access = private)
        %% find_closest_trial
            % Find the trial in the dataset that's closest to the input
            % parameters
        function find_closest_trial(app)
            % Find which trial best matches the input values
            distances = sqrt((app.sumvars.AMP_WK - app.AMPWK).^2 + ...
                 (app.sumvars.Tperiod - app.TPERIOD).^2 + ...
                 (app.sumvars.SLP - app.SLP).^2);
            [~, app.ind] = min(distances);
        end

        %% plot_animation
            % Plot an animation of the time series
        function plot_bathy(app)
            %%% Get Variables to Plot
                Xslp = app.sumvars{app.ind,'Xslp'};
                dep = -app.sumconst.DEPTH_FLAT;
                Xc_WK_p = app.sumconst.Xc_WK;
                Sp_W = app.sumconst.Sponge_west_width;
            %%% Plot the Bathymetry
            % Plot Setup
                cla(app.UIAxes) 
                hold(app.UIAxes,"on")
            % Plot Calls
                % Bed   
                    plot(app.UIAxes,[0,Xslp],[dep,dep],'Color','k','LineWidth',2)
                    plot(app.UIAxes,[Xslp,1024],[dep,0],'Color','k','LineWidth',2)
                % Location of Xslp
                    scatter(app.UIAxes,Xslp,dep, 'filled', 'o','MarkerEdgeColor','m','MarkerFaceColor','m');
                % Location of Sponge Layer
                    xline(app.UIAxes,Sp_W,'Color','r','LineWidth',2, 'LineStyle','--');
                % Location of Wavemaker
                    xline(app.UIAxes,Xc_WK_p,'Color','b','LineWidth',2, 'LineStyle','--');
            % Plot Labels and Formatting 
                grid(app.UIAxes,'on')
                ylim(app.UIAxes,[-10 3])
        end

        function plot_animation(app)
            %%% Construct the subtitle with parameter values
                SLP_s = num2str(app.sumvars{app.ind,'SLP'});
                AMP_WK_s = num2str(app.sumvars{app.ind,'AMP_WK'});
                Tperiod_s = num2str(app.sumvars{app.ind,'Tperiod'});
                Xslp_s = num2str(app.sumvars{app.ind,'Xslp'});
                titlename = {['SLP = ', SLP_s], ...
                    ['AMP_WK = ', AMP_WK_s],...
                    ['Tperiod = ', Tperiod_s],...
                    ['Xslp = ', Xslp_s]};
                subtitle(app.UIAxes, titlename)

            %%% Animate the Time Series
            % Plot the first element and initialize counter
                p = plot(app.UIAxes,squeeze(app.NPY(app.ind,1,:)));
                iter = 1;
            % Plot Labels
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes, 'time (s)');
                ylabel(app.UIAxes, '\eta');

            % Loop through all time steps
                for j = 1:600
                    % Only progress if Stop isn't checked
                    if app.stop == false
                        % Update plot elements
                        set(p, 'YData', squeeze(app.NPY(app.ind,j,:)))
                        title(app.UIAxes, ['Model Run ', num2str(app.ind), ' Time = ', num2str(j)]);
    
                        % Add legends for first iteration
                        if iter == 1
                            legend(app.UIAxes,'','','Xslp','Sponge Layer', 'WaveMaker','eta', 'Location','southwest');
                        end
                        
                        iter = iter + 1;
                        % Draw now to update
                        drawnow

                    % If stop is Checked, then break out of loop
                    else
                        break
                    end
                end
        end

                
        %% populate_constants
            % Populate the values of the constants on the left
        function populate_constants(app)
            sumconsth = app.sumconst;
            app.Mglob.Value = sumconsth.Mglob;
            app.Nglob.Value = sumconsth.Nglob;

            app.DX.Value = sumconsth.DX;
            app.DY.Value = sumconsth.DY;

            app.PX.Value = sumconsth.PX;
            app.PY.Value = sumconsth.PY;

            app.TOTAL_TIME.Value = sumconsth.TOTAL_TIME;
            app.PLOT_INTV.Value = sumconsth.PLOT_INTV;
            app.SCREEN_INTV.Value = sumconsth.SCREEN_INTV;

            app.CFL.Value = sumconsth.CFL;
            app.FroudeCap.Value= sumconsth.FroudeCap;
            app.MinDepth.Value= sumconsth.MinDepth;

            app.WAVEMAKER.Value = sumconsth.WAVEMAKER{1};
            app.DEP_WK.Value = sumconsth.DEP_WK;
            app.Xc_WK.Value = sumconsth.Xc_WK;
            app.Theta_WK.Value = sumconsth.Theta_WK;
            app.DIFFUSIONSPONGE.Value = sumconsth.DIFFUSION_SPONGE{1};
            app.FRICTIONSPONGE.Value = sumconsth.FRICTION_SPONGE{1};
            app.DIRECTSPONGE.Value = sumconsth.DIRECT_SPONGE{1};

            app.Sponge_Csp.Value = sumconsth.Csp;
            app.Cd.Value = sumconsth.CDsponge;

            app.Sponge_S.Value = sumconsth.Sponge_south_width;
            app.Sponge_N.Value = sumconsth.Sponge_north_width;
            app.Sponge_W.Value = sumconsth.Sponge_west_width;
            app.Sponge_E.Value = sumconsth.Sponge_east_width;

            app.VISCOSITYBREAKING.Value = sumconsth.VISCOSITY_BREAKING{1};
            app.T_INTVMEAN.Value = sumconsth.T_INTV_mean;

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SLPEditField
        function SLPEditFieldValueChanged(app, event)
            app.SLP = app.SLPEditField.Value;
        end

        % Value changed function: AMP_WKEditField
        function AMP_WKEditFieldValueChanged(app, event)
            app.AMPWK = app.AMP_WKEditField.Value;
        end

        % Value changed function: TPERIODEditField
        function TPERIODEditFieldValueChanged(app, event)
            app.TPERIOD = app.TPERIODEditField.Value;
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            find_closest_trial(app);
            plot_bathy(app);
            plot_animation(app);


        end

        % Button pushed function: LoadDataSelectFolderButton
        function LoadDataSelectFolderButtonPushed(app, event)
            % Dummy Figure for UI Ease
                f = figure('Position', [-100 -100 0 0]);
            % Select Path
                selpath = uigetdir();
            % Delete Dummy Figure
                delete(f);
            
            % Save paths to data
                app.NPY = readNPY(fullfile(selpath,'data_1D.npy'));
                app.sumvars = readtable(fullfile(selpath,'sumvars.txt'));
                app.sumconst = readtable(fullfile(selpath,'sumconst.txt'));
            
            % Display Folder Selected    
                app.FolderSelectedEditField.Value = selpath;

            % Populate sidebar with information about constants
                populate_constants(app);

        end

        % Value changed function: StopCheckBox
        function StopCheckBoxValueChanged(app, event)
            value = app.StopCheckBox.Value;
            app.stop = value;

            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {861, 861};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {292, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnimateButton = uibutton(app.LeftPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.SLPEditFieldLabel = uilabel(app.LeftPanel);
            app.SLPEditFieldLabel.Text = 'SLP';
            app.SLPEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SLPEditField.ValueChangedFcn = createCallbackFcn(app, @SLPEditFieldValueChanged, true);
            app.SLPEditField.Value = 0.05;
            app.AMP_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.AMP_WKEditFieldLabel.Text = 'AMP_WK';
            app.AMP_WKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AMP_WKEditField.ValueChangedFcn = createCallbackFcn(app, @AMP_WKEditFieldValueChanged, true);
            app.AMP_WKEditField.Value = 1;
            app.TPERIODEditFieldLabel = uilabel(app.LeftPanel);
            app.TPERIODEditFieldLabel.Text = 'TPERIOD';
            app.TPERIODEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TPERIODEditField.ValueChangedFcn = createCallbackFcn(app, @TPERIODEditFieldValueChanged, true);
            app.TPERIODEditField.Value = 9;
            app.FunwaveVariableParametersLabel = uilabel(app.LeftPanel);
            app.FunwaveVariableParametersLabel.Text = 'Funwave Variable Parameters';
            app.LoadDataSelectFolderButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataSelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataSelectFolderButtonPushed, true);
            app.LoadDataSelectFolderButton.Text = 'Load Data: Select Folder';
            app.LoadinDataLabel = uilabel(app.LeftPanel);
            app.LoadinDataLabel.Text = 'Load in Data';
            app.FolderSelectedEditFieldLabel = uilabel(app.LeftPanel);
            app.FolderSelectedEditFieldLabel.Text = 'Folder Selected';
            app.FolderSelectedEditField = uieditfield(app.LeftPanel, 'text');
            app.FunwaveConstantParametersLabel = uilabel(app.LeftPanel);
            app.FunwaveConstantParametersLabel.Text = 'Funwave Constant Parameters';
            app.MglobEditFieldLabel = uilabel(app.LeftPanel);
            app.MglobEditFieldLabel.Text = 'Mglob';
            app.Mglob = uieditfield(app.LeftPanel, 'numeric');
            app.NglobEditFieldLabel = uilabel(app.LeftPanel);
            app.NglobEditFieldLabel.Text = 'Nglob';
            app.Nglob = uieditfield(app.LeftPanel, 'numeric');
            app.DXEditFieldLabel = uilabel(app.LeftPanel);
            app.DXEditFieldLabel.Text = 'DX';
            app.DX = uieditfield(app.LeftPanel, 'numeric');
            app.DYEditFieldLabel = uilabel(app.LeftPanel);
            app.DYEditFieldLabel.Text = 'DY';
            app.DY = uieditfield(app.LeftPanel, 'numeric');
            app.PXEditFieldLabel = uilabel(app.LeftPanel);
            app.PXEditFieldLabel.Text = 'PX';
            app.PX = uieditfield(app.LeftPanel, 'numeric');
            app.PYEditFieldLabel = uilabel(app.LeftPanel);
            app.PYEditFieldLabel.Text = 'PY';
            app.PY = uieditfield(app.LeftPanel, 'numeric');
            app.TOTAL_TIMEEditFieldLabel = uilabel(app.LeftPanel);
            app.TOTAL_TIMEEditFieldLabel.Text = 'TOTAL_TIME';
            app.TOTAL_TIME = uieditfield(app.LeftPanel, 'numeric');
            app.CFLEditFieldLabel = uilabel(app.LeftPanel);
            app.CFLEditFieldLabel.Text = 'CFL';
            app.CFL = uieditfield(app.LeftPanel, 'numeric');
            app.PLOT_INTVEditFieldLabel = uilabel(app.LeftPanel);
            app.PLOT_INTVEditFieldLabel.Text = 'PLOT_INTV';
            app.PLOT_INTV = uieditfield(app.LeftPanel, 'numeric');
            app.SCREEN_INTVEditFieldLabel = uilabel(app.LeftPanel);
            app.SCREEN_INTVEditFieldLabel.Text = 'SCREEN_INTV';
            app.SCREEN_INTV = uieditfield(app.LeftPanel, 'numeric');
            app.FroudeCapEditFieldLabel = uilabel(app.LeftPanel);
            app.FroudeCapEditFieldLabel.Text = 'FroudeCap';
            app.FroudeCap = uieditfield(app.LeftPanel, 'numeric');
            app.MinDepthEditFieldLabel = uilabel(app.LeftPanel);
            app.MinDepthEditFieldLabel.Text = 'MinDepth';
            app.MinDepth = uieditfield(app.LeftPanel, 'numeric');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '______________________________';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '______________________________';
            app.Xc_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.Xc_WKEditFieldLabel.Text = 'Xc_WK';
            app.Xc_WK = uieditfield(app.LeftPanel, 'numeric');
            app.Theta_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.Theta_WKEditFieldLabel.Text = 'Theta_WK';
            app.Theta_WK = uieditfield(app.LeftPanel, 'numeric');
            app.DEP_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.DEP_WKEditFieldLabel.Text = 'DEP_WK';
            app.DEP_WK = uieditfield(app.LeftPanel, 'numeric');
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '______________________________';
            app.VISCOSITYBREAKINGEditFieldLabel = uilabel(app.LeftPanel);
            app.VISCOSITYBREAKINGEditFieldLabel.Text = {'VISCOSITY'; 'BREAKING'};
            app.VISCOSITYBREAKING = uieditfield(app.LeftPanel, 'text');
            app.FRICTIONSPONGEEditFieldLabel = uilabel(app.LeftPanel);
            app.FRICTIONSPONGEEditFieldLabel.Text = {'FRICTION '; 'SPONGE'};
            app.FRICTIONSPONGE = uieditfield(app.LeftPanel, 'text');
            app.DIRECTSPONGEEditFieldLabel = uilabel(app.LeftPanel);
            app.DIRECTSPONGEEditFieldLabel.Text = {'DIRECT'; 'SPONGE'};
            app.DIRECTSPONGE = uieditfield(app.LeftPanel, 'text');
            app.CDSpongeEditFieldLabel = uilabel(app.LeftPanel);
            app.CDSpongeEditFieldLabel.Text = 'CDSponge';
            app.CDSponge = uieditfield(app.LeftPanel, 'numeric');
            app.CspEditFieldLabel = uilabel(app.LeftPanel);
            app.CspEditFieldLabel.Text = 'Csp';
            app.Sponge_Csp = uieditfield(app.LeftPanel, 'numeric');
            app.WEditFieldLabel = uilabel(app.LeftPanel);
            app.WEditFieldLabel.Text = 'W';
            app.Sponge_W = uieditfield(app.LeftPanel, 'numeric');
            app.EEditFieldLabel = uilabel(app.LeftPanel);
            app.EEditFieldLabel.Text = 'E';
            app.Sponge_E = uieditfield(app.LeftPanel, 'numeric');
            app.SEditFieldLabel = uilabel(app.LeftPanel);
            app.SEditFieldLabel.Text = 'S';
            app.Sponge_S = uieditfield(app.LeftPanel, 'numeric');
            app.NEditFieldLabel = uilabel(app.LeftPanel);
            app.NEditFieldLabel.Text = 'N';
            app.Sponge_N = uieditfield(app.LeftPanel, 'numeric');
            app.SpongeDimensionsLabel = uilabel(app.LeftPanel);
            app.SpongeDimensionsLabel.Text = 'Sponge Dimensions';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '______________________________';
            app.CdEditFieldLabel = uilabel(app.LeftPanel);
            app.CdEditFieldLabel.Text = 'Cd';
            app.Cd = uieditfield(app.LeftPanel, 'numeric');
            app.Cbrk1EditFieldLabel = uilabel(app.LeftPanel);
            app.Cbrk1EditFieldLabel.Text = 'Cbrk1';
            app.Cbrk1 = uieditfield(app.LeftPanel, 'numeric');
            app.Cbrk2EditFieldLabel = uilabel(app.LeftPanel);
            app.Cbrk2EditFieldLabel.Text = 'Cbrk2';
            app.Cbrk2 = uieditfield(app.LeftPanel, 'numeric');
            app.DIFFUSIONSPONGEEditField_2Label = uilabel(app.LeftPanel);
            app.DIFFUSIONSPONGEEditField_2Label.Text = {'DIFFUSION'; 'SPONGE'};
            app.DIFFUSIONSPONGE = uieditfield(app.LeftPanel, 'text');
            app.WAVEMAKEREditFieldLabel = uilabel(app.LeftPanel);
            app.WAVEMAKEREditFieldLabel.Text = {'WAVE'; 'MAKER'};
            app.WAVEMAKER = uieditfield(app.LeftPanel, 'text');
            app.T_INTVMEANEditFieldLabel = uilabel(app.LeftPanel);
            app.T_INTVMEANEditFieldLabel.Text = {'T_INTV'; 'MEAN'};
            app.T_INTVMEAN = uieditfield(app.LeftPanel, 'numeric');
            app.StopCheckBox = uicheckbox(app.LeftPanel);
            app.StopCheckBox.ValueChangedFcn = createCallbackFcn(app, @StopCheckBoxValueChanged, true);
            app.StopCheckBox.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'FUNWAVE 1D Simulation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnimateTrial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end