classdef ViewMLResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DerivedStatisticsLabel          matlab.ui.control.Label
        T_INTVMEAN                      matlab.ui.control.NumericEditField
        T_INTVMEANEditFieldLabel        matlab.ui.control.Label
        WAVEMAKER                       matlab.ui.control.EditField
        WAVEMAKEREditFieldLabel         matlab.ui.control.Label
        DIFFUSIONSPONGE                 matlab.ui.control.EditField
        DIFFUSIONSPONGEEditField_2Label  matlab.ui.control.Label
        Cbrk2                           matlab.ui.control.NumericEditField
        Cbrk2EditFieldLabel             matlab.ui.control.Label
        Cbrk1                           matlab.ui.control.NumericEditField
        Cbrk1EditFieldLabel             matlab.ui.control.Label
        Cd                              matlab.ui.control.NumericEditField
        CdEditFieldLabel                matlab.ui.control.Label
        Sponge_N                        matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        Sponge_S                        matlab.ui.control.NumericEditField
        SEditFieldLabel                 matlab.ui.control.Label
        Sponge_E                        matlab.ui.control.NumericEditField
        EEditFieldLabel                 matlab.ui.control.Label
        Sponge_W                        matlab.ui.control.NumericEditField
        WEditFieldLabel                 matlab.ui.control.Label
        Sponge_Csp                      matlab.ui.control.NumericEditField
        CspEditFieldLabel               matlab.ui.control.Label
        CDSponge                        matlab.ui.control.NumericEditField
        CDSpongeEditFieldLabel          matlab.ui.control.Label
        DIRECTSPONGE                    matlab.ui.control.EditField
        DIRECTSPONGEEditFieldLabel      matlab.ui.control.Label
        FRICTIONSPONGE                  matlab.ui.control.EditField
        FRICTIONSPONGEEditFieldLabel    matlab.ui.control.Label
        VISCOSITYBREAKING               matlab.ui.control.EditField
        VISCOSITYBREAKINGEditFieldLabel  matlab.ui.control.Label
        DEP_WK                          matlab.ui.control.NumericEditField
        DEP_WKEditFieldLabel            matlab.ui.control.Label
        Theta_WK                        matlab.ui.control.NumericEditField
        Theta_WKEditFieldLabel          matlab.ui.control.Label
        Xc_WK_Box                       matlab.ui.control.NumericEditField
        Xc_WKEditFieldLabel             matlab.ui.control.Label
        MinDepth                        matlab.ui.control.NumericEditField
        MinDepthEditFieldLabel          matlab.ui.control.Label
        FroudeCap                       matlab.ui.control.NumericEditField
        FroudeCapEditFieldLabel         matlab.ui.control.Label
        CFL                             matlab.ui.control.NumericEditField
        CFLEditFieldLabel               matlab.ui.control.Label
        SCREEN_INTV                     matlab.ui.control.NumericEditField
        SCREEN_INTVEditFieldLabel       matlab.ui.control.Label
        PLOT_INTV                       matlab.ui.control.NumericEditField
        PLOT_INTVEditFieldLabel         matlab.ui.control.Label
        TOTAL_TIME                      matlab.ui.control.NumericEditField
        TOTAL_TIMEEditFieldLabel        matlab.ui.control.Label
        PY                              matlab.ui.control.NumericEditField
        PYEditFieldLabel                matlab.ui.control.Label
        PX                              matlab.ui.control.NumericEditField
        PXEditFieldLabel                matlab.ui.control.Label
        DY                              matlab.ui.control.NumericEditField
        DYEditFieldLabel                matlab.ui.control.Label
        DX                              matlab.ui.control.NumericEditField
        DXEditFieldLabel                matlab.ui.control.Label
        Nglob                           matlab.ui.control.NumericEditField
        NglobEditFieldLabel             matlab.ui.control.Label
        Mglob                           matlab.ui.control.NumericEditField
        MglobEditFieldLabel             matlab.ui.control.Label
        SpongeDimensionsLabel           matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        FunwaveConstantParametersLabel  matlab.ui.control.Label
        DataFolder                      matlab.ui.control.EditField
        LoadDataB                       matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        ML_Plot                         matlab.ui.control.UIAxes
        SkasyGraph                      matlab.ui.control.UIAxes
        BathyGraph                      matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        SkewAsymmetryLabel              matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        TrueValuesPointLabel            matlab.ui.control.Label
        Xslp_Box                        matlab.ui.control.NumericEditField
        IterationLabel_5                matlab.ui.control.Label
        Input_TPERIOD                   matlab.ui.control.NumericEditField
        IterationLabel_4                matlab.ui.control.Label
        Input_AMP_WK                    matlab.ui.control.NumericEditField
        IterationLabel_3                matlab.ui.control.Label
        Input_SLP                       matlab.ui.control.NumericEditField
        IterationLabel_2                matlab.ui.control.Label
        Iteration_No                    matlab.ui.control.NumericEditField
        IterationLabel                  matlab.ui.control.Label
        Asy                             matlab.ui.control.NumericEditField
        AsyLabel_2                      matlab.ui.control.Label
        Skew                            matlab.ui.control.NumericEditField
        SkewEditFieldLabel              matlab.ui.control.Label
        TrialBeingPlottedLabel          matlab.ui.control.Label
        TPERIODEditField                matlab.ui.control.NumericEditField
        TPERIODEditFieldLabel           matlab.ui.control.Label
        AMP_WKEditField                 matlab.ui.control.NumericEditField
        AMP_WKEditFieldLabel            matlab.ui.control.Label
        SLPEditField                    matlab.ui.control.NumericEditField
        SLPEditFieldLabel               matlab.ui.control.Label
        PointtoPlotEditField            matlab.ui.control.NumericEditField
        PointtoPlotEditFieldLabel       matlab.ui.control.Label
        FunwaveVariableParametersLabel  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        %%% Raw Data
            NPY 
            sumvars
            sumconst
        %%% Skew and Asymmetry Data
            skew_tab
            asy_tab
            start_t_tab

        %%% Default Variables
            % FUNWAVE Variable inputs
                SLP=0.05    
                AMPWK=1
                TPERIOD=9
            % Point in the model domain to plot
                point=900
                Xslp
                
        %%% Index of closest matching trial
            ind = 65;
        
        %%% Parquet-based tables of ML results
            NN
            LR
        
        %%% Indexed-out results from Parquets
            NN_cur
            LR_cur

    end
    
    methods (Access = private)
        
        %% find_closest_trial
            % Find the trial in the dataset that's closest to the input
            % parameters
        function find_closest_trial(app)
            % Find which trial best matches the input values
            distances = sqrt((app.sumvars.AMP_WK - app.AMPWK).^2 + ...
                 (app.sumvars.Tperiod - app.TPERIOD).^2 + ...
                 (app.sumvars.SLP - app.SLP).^2);
            [~, app.ind] = min(distances);

            % Update the ML results
            app.NN_cur = app.NN(app.NN.iter == app.ind, :);
            app.LR_cur = app.LR(app.LR.iter == app.ind, :);
        end

        %% plot_time_series
            % Plot the time series
        function plot_time_series(app)
            %%% Get variables to plot
            % Pull out time series
                eta = squeeze(app.NPY(app.ind,:,app.point));
            % Pull out start time for skew/asymmetry
                start_time = app.start_t_tab{app.ind,app.point};

            %%% Plot the time series
            % Plot Setup
                cla(app.UIAxes);
                hold(app.UIAxes,"on")
            % Plot Calls
                plot(app.UIAxes,eta)
                scatter(app.UIAxes,start_time,eta(start_time), 'filled', 'o','MarkerEdgeColor','r','MarkerFaceColor','r')
            % Plot Labels and Formatting 
                legend(app.UIAxes,'','Start Time');
                title(app.UIAxes, ['Time Series at Point ', num2str(app.point)]);
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes, 'time (s)');
                ylabel(app.UIAxes, '\eta');
            
            %%% Sidebar Information
            % Funwave Constants and Info
                app.Iteration_No.Value = app.ind;
                app.Input_SLP.Value = app.sumvars{app.ind,'SLP'};
                app.Input_AMP_WK.Value = app.sumvars{app.ind,'AMP_WK'};
                app.Input_TPERIOD.Value = app.sumvars{app.ind,'Tperiod'};
                app.Xslp_Box.Value = app.sumvars{app.ind,'Xslp'};
                app.Xslp = app.sumvars{app.ind,'Xslp'};
            % Skew/Asymmetry
                app.Skew.Value = app.skew_tab{app.ind,app.point};
                app.Asy.Value = app.asy_tab{app.ind,app.point};
        end
        
        %% plot_bathy
            % Plot the bathymetry
        function plot_bathy(app)
            %%% Pull out relevant variables
                Xslp = app.sumvars{app.ind,'Xslp'};
                dep = -app.sumconst.DEPTH_FLAT;
                Xc_WK = app.sumconst.Xc_WK;
                Sp_W = app.sumconst.Sponge_west_width;

            %%% Plot the bathymetry
            % Plot Setup
                cla(app.BathyGraph) 
                hold(app.BathyGraph,"on")
            % Plot Calls
                % Bed
                    plot(app.BathyGraph,[0,Xslp],[dep,dep],'Color','k','LineWidth',2)
                    plot(app.BathyGraph,[Xslp,1024],[dep,0],'Color','k','LineWidth',2)
                % Location of Xslp
                    scatter(app.BathyGraph,Xslp,dep, 'filled', 'o','MarkerEdgeColor','m','MarkerFaceColor','m');
                % Location of Sponge Layer
                    xline(app.BathyGraph,Sp_W,'Color','r','LineWidth',2, 'LineStyle','--');
                % Location of Wavemaker
                    xline(app.BathyGraph,Xc_WK,'Color','b','LineWidth',2, 'LineStyle','--');
                % Location of Point being plotted
                xline(app.BathyGraph,app.point,'Color','g','LineWidth',2, 'LineStyle','--');
            % Plot Labels and Formatting 
                grid(app.BathyGraph,'on')
                xlim(app.BathyGraph,[0,1024]);
                legend(app.BathyGraph,'','','Xslp','Sponge Layer', 'WaveMaker', 'Point of Time Series','Location','NorthWest');
        end
        
        %% plot_skasy
            % Plot the skew and asymmetry
        function plot_skasy(app)
            %%% Get Variables to Plot
                 skew_p = app.skew_tab{app.ind,2:end};
                 asy_p = app.asy_tab{app.ind,2:end};
                 Xc_WK = app.sumconst.Xc_WK;
                 Sp_W = app.sumconst.Sponge_west_width;
            %%% Plot the Skew and Asymmetry
            % Plot Setup
                cla(app.SkasyGraph)
                hold(app.SkasyGraph,"on");
            % Plot Calls
                % Skew and Asymmetry
                    plot(app.SkasyGraph,1:1024,skew_p,'Color','b');
                    plot(app.SkasyGraph,1:1024,asy_p,'Color','r');
                % Location of Sponge Layer
                    xline(app.SkasyGraph,Sp_W,'Color','r','LineWidth',2, 'LineStyle','--');
                % Location of Wavemaker
                    xline(app.SkasyGraph,Xc_WK,'Color','b','LineWidth',2, 'LineStyle','--');
                % Location of Point being plotted
                    xline(app.SkasyGraph,app.point,'Color','g','LineWidth',2, 'LineStyle','--');
            % Plot Labels and Formatting 
                xlim(app.SkasyGraph,[0,1024]);
                legend(app.SkasyGraph,'Skew','Asymmetry','Sponge Layer', 'WaveMaker', 'Point of Time Series','Location','Southeast');
                grid(app.SkasyGraph,'on')
        end

        function plot_ml(app)
            %%% Get Variables to Plot
                 % POSITION
                    pos = app.NN_cur.pos;
                 % SKEW
                    skew_true = app.NN_cur.skew_TRUE;
                    skew_NN = app.NN_cur.skew;
                    skew_LR = app.LR_cur.skew;
                 % ASYMMETRY 
                    asy_true = app.NN_cur.asy_TRUE;
                    asy_NN = app.NN_cur.asy;
                    asy_LR = app.LR_cur.asy;
            %%% Plot the Skew and Asymmetry
            % Plot Setup
                cla(app.ML_Plot)
                hold(app.ML_Plot,"on");
            % Plot Calls
                % Plot True Values
                    plot(app.ML_Plot,pos,skew_true,'Color','b');
                    plot(app.ML_Plot,pos,asy_true,'Color','r');
                % Plot LR Values
                    plot(app.ML_Plot,pos,skew_LR,'Color','b', 'LineStyle','-.','LineWidth',1.5);
                    plot(app.ML_Plot,pos,asy_LR,'Color','r', 'LineStyle','-.','LineWidth',1.5);
                % Plot NN Values
                    plot(app.ML_Plot,pos,skew_NN,'Color','b', 'LineStyle','--','LineWidth',2);
                    plot(app.ML_Plot,pos,asy_NN,'Color','r', 'LineStyle','--','LineWidth',2);
                
            % Plot Labels and Formatting 
                %xlim(app.ML_Plot,[0,1024]);
                legend(app.ML_Plot,'Actual Skew',' Actual Asymmetry','LR Skew', 'LR Asymmetry', 'NN Skew', 'NN Asymmetry', 'Location','eastoutside');
                grid(app.ML_Plot,'on')

            % Put on sidebars
            % if app.point > app.Xslp
            %     [~, index] = min(abs(app.LR_cur.pos - app.Xslp));
            %     app.SkewLR.Value  = app.LR_cur.skew(index);
            %     app.AsyLR.Value = app.LR_cur.asy(index);
            %     %app.SkewNN.Value = app.NN_cur.skew(index);
            %     app.AsyNN.Value = app.NN_cur.asy(index);
            % else
            %     app.SkewLR.Value  = 0;
            %     app.AsyLR.Value = 0;
            %     app.SkewNN.Value = 0;
            %     app.AsyNN.Value = 0;
            % end

        end


        %% populate_constants
            % Populate the values of the constants on the left
        function populate_constants(app)
            sumconsth = app.sumconst;
            app.Mglob.Value = sumconsth.Mglob;
            app.Nglob.Value = sumconsth.Nglob;

            app.DX.Value = sumconsth.DX;
            app.DY.Value = sumconsth.DY;

            app.PX.Value = sumconsth.PX;
            app.PY.Value = sumconsth.PY;

            app.TOTAL_TIME.Value = sumconsth.TOTAL_TIME;
            app.PLOT_INTV.Value = sumconsth.PLOT_INTV;
            app.SCREEN_INTV.Value = sumconsth.SCREEN_INTV;

            app.CFL.Value = sumconsth.CFL;
            app.FroudeCap.Value= sumconsth.FroudeCap;
            app.MinDepth.Value= sumconsth.MinDepth;

            app.WAVEMAKER.Value = sumconsth.WAVEMAKER{1};
            app.DEP_WK.Value = sumconsth.DEP_WK;
            app.Xc_WK_Box.Value = sumconsth.Xc_WK;
            app.Theta_WK.Value = sumconsth.Theta_WK;
            app.DIFFUSIONSPONGE.Value = sumconsth.DIFFUSION_SPONGE{1};
            app.FRICTIONSPONGE.Value = sumconsth.FRICTION_SPONGE{1};
            app.DIRECTSPONGE.Value = sumconsth.DIRECT_SPONGE{1};

            app.Sponge_Csp.Value = sumconsth.Csp;
            app.CDSponge.Value = sumconsth.CDsponge;

            app.Sponge_S.Value = sumconsth.Sponge_south_width;
            app.Sponge_N.Value = sumconsth.Sponge_north_width;
            app.Sponge_W.Value = sumconsth.Sponge_west_width;
            app.Sponge_E.Value = sumconsth.Sponge_east_width;

            app.VISCOSITYBREAKING.Value = sumconsth.VISCOSITY_BREAKING{1};
            app.T_INTVMEAN.Value = sumconsth.T_INTV_mean;

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataB
        function LoadDataBButtonPushed(app, event)
            %%% Select Path
            % Dummy Figure for UI Ease
                f = figure('Position', [-100 -100 0 0]);
            % Select Path
                rawpath = uigetdir();
                skasypath = fullfile(rawpath,'Skasy');
            % Delete Dummy Figure
                delete(f);

            % Save Raw Data
                app.NPY = readNPY(fullfile(rawpath,'data_1D.npy'));
                app.sumvars = readtable(fullfile(rawpath,'sumvars.txt'));
                app.sumconst = readtable(fullfile(rawpath,'sumconst.txt'));
            % Save Skew and Asymmetry
                app.skew_tab = readtable(fullfile(skasypath,'skew_tab.txt'),'ReadVariableNames',true);
                app.asy_tab = readtable(fullfile(skasypath,'asy_tab.txt'),'ReadVariableNames',true);
                app.start_t_tab = readtable(fullfile(skasypath,'start_t_tab.txt'),'ReadVariableNames',true);

            % Get ML results
                app.LR = parquetread(fullfile(rawpath,'LR_summary.parquet'));
                app.NN = parquetread(fullfile(rawpath,'NN_summary.parquet'));
            
            % Display Folder Selected
                app.DataFolder.Value = rawpath;
            % Populate sidebar with information about constants
                populate_constants(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            find_closest_trial(app);
            plot_time_series(app);
            plot_bathy(app);
            plot_ml(app);
            plot_skasy(app);
        end

        % Value changed function: PointtoPlotEditField
        function PointtoPlotEditFieldValueChanged(app, event)
            app.point = app.PointtoPlotEditField.Value;
            
        end

        % Value changed function: SLPEditField
        function SLPEditFieldValueChanged(app, event)
            app.SLP = app.SLPEditField.Value;
            
        end

        % Value changed function: AMP_WKEditField
        function AMP_WKEditFieldValueChanged(app, event)
            app.AMPWK = app.AMP_WKEditField.Value;
            
        end

        % Value changed function: TPERIODEditField
        function TPERIODEditFieldValueChanged(app, event)
            app.TPERIOD = app.TPERIODEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {881, 881, 881};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {881, 881};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x', 191};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataB = uibutton(app.LeftPanel, 'push');
            app.LoadDataB.ButtonPushedFcn = createCallbackFcn(app, @LoadDataBButtonPushed, true);
            app.LoadDataB.Text = 'Load Data';
            app.DataFolder = uieditfield(app.LeftPanel, 'text');
            app.FunwaveConstantParametersLabel = uilabel(app.LeftPanel);
            app.FunwaveConstantParametersLabel.Text = 'Funwave Constant Parameters';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '______________________________';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '______________________________';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '______________________________';
            app.SpongeDimensionsLabel = uilabel(app.LeftPanel);
            app.SpongeDimensionsLabel.Text = 'Sponge Dimensions';
            app.MglobEditFieldLabel = uilabel(app.LeftPanel);
            app.MglobEditFieldLabel.Text = 'Mglob';
            app.Mglob = uieditfield(app.LeftPanel, 'numeric');
            app.NglobEditFieldLabel = uilabel(app.LeftPanel);
            app.NglobEditFieldLabel.Text = 'Nglob';
            app.Nglob = uieditfield(app.LeftPanel, 'numeric');
            app.DXEditFieldLabel = uilabel(app.LeftPanel);
            app.DXEditFieldLabel.Text = 'DX';
            app.DX = uieditfield(app.LeftPanel, 'numeric');
            app.DYEditFieldLabel = uilabel(app.LeftPanel);
            app.DYEditFieldLabel.Text = 'DY';
            app.DY = uieditfield(app.LeftPanel, 'numeric');
            app.PXEditFieldLabel = uilabel(app.LeftPanel);
            app.PXEditFieldLabel.Text = 'PX';
            app.PX = uieditfield(app.LeftPanel, 'numeric');
            app.PYEditFieldLabel = uilabel(app.LeftPanel);
            app.PYEditFieldLabel.Text = 'PY';
            app.PY = uieditfield(app.LeftPanel, 'numeric');
            app.TOTAL_TIMEEditFieldLabel = uilabel(app.LeftPanel);
            app.TOTAL_TIMEEditFieldLabel.Text = 'TOTAL_TIME';
            app.TOTAL_TIME = uieditfield(app.LeftPanel, 'numeric');
            app.PLOT_INTVEditFieldLabel = uilabel(app.LeftPanel);
            app.PLOT_INTVEditFieldLabel.Text = 'PLOT_INTV';
            app.PLOT_INTV = uieditfield(app.LeftPanel, 'numeric');
            app.SCREEN_INTVEditFieldLabel = uilabel(app.LeftPanel);
            app.SCREEN_INTVEditFieldLabel.Text = 'SCREEN_INTV';
            app.SCREEN_INTV = uieditfield(app.LeftPanel, 'numeric');
            app.CFLEditFieldLabel = uilabel(app.LeftPanel);
            app.CFLEditFieldLabel.Text = 'CFL';
            app.CFL = uieditfield(app.LeftPanel, 'numeric');
            app.FroudeCapEditFieldLabel = uilabel(app.LeftPanel);
            app.FroudeCapEditFieldLabel.Text = 'FroudeCap';
            app.FroudeCap = uieditfield(app.LeftPanel, 'numeric');
            app.MinDepthEditFieldLabel = uilabel(app.LeftPanel);
            app.MinDepthEditFieldLabel.Text = 'MinDepth';
            app.MinDepth = uieditfield(app.LeftPanel, 'numeric');
            app.Xc_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.Xc_WKEditFieldLabel.Text = 'Xc_WK';
            app.Xc_WK_Box = uieditfield(app.LeftPanel, 'numeric');
            app.Theta_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.Theta_WKEditFieldLabel.Text = 'Theta_WK';
            app.Theta_WK = uieditfield(app.LeftPanel, 'numeric');
            app.DEP_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.DEP_WKEditFieldLabel.Text = 'DEP_WK';
            app.DEP_WK = uieditfield(app.LeftPanel, 'numeric');
            app.VISCOSITYBREAKINGEditFieldLabel = uilabel(app.LeftPanel);
            app.VISCOSITYBREAKINGEditFieldLabel.Text = {'VISCOSITY'; 'BREAKING'};
            app.VISCOSITYBREAKING = uieditfield(app.LeftPanel, 'text');
            app.FRICTIONSPONGEEditFieldLabel = uilabel(app.LeftPanel);
            app.FRICTIONSPONGEEditFieldLabel.Text = {'FRICTION '; 'SPONGE'};
            app.FRICTIONSPONGE = uieditfield(app.LeftPanel, 'text');
            app.DIRECTSPONGEEditFieldLabel = uilabel(app.LeftPanel);
            app.DIRECTSPONGEEditFieldLabel.Text = {'DIRECT'; 'SPONGE'};
            app.DIRECTSPONGE = uieditfield(app.LeftPanel, 'text');
            app.CDSpongeEditFieldLabel = uilabel(app.LeftPanel);
            app.CDSpongeEditFieldLabel.Text = 'CDSponge';
            app.CDSponge = uieditfield(app.LeftPanel, 'numeric');
            app.CspEditFieldLabel = uilabel(app.LeftPanel);
            app.CspEditFieldLabel.Text = 'Csp';
            app.Sponge_Csp = uieditfield(app.LeftPanel, 'numeric');
            app.WEditFieldLabel = uilabel(app.LeftPanel);
            app.WEditFieldLabel.Text = 'W';
            app.Sponge_W = uieditfield(app.LeftPanel, 'numeric');
            app.EEditFieldLabel = uilabel(app.LeftPanel);
            app.EEditFieldLabel.Text = 'E';
            app.Sponge_E = uieditfield(app.LeftPanel, 'numeric');
            app.SEditFieldLabel = uilabel(app.LeftPanel);
            app.SEditFieldLabel.Text = 'S';
            app.Sponge_S = uieditfield(app.LeftPanel, 'numeric');
            app.NEditFieldLabel = uilabel(app.LeftPanel);
            app.NEditFieldLabel.Text = 'N';
            app.Sponge_N = uieditfield(app.LeftPanel, 'numeric');
            app.CdEditFieldLabel = uilabel(app.LeftPanel);
            app.CdEditFieldLabel.Text = 'Cd';
            app.Cd = uieditfield(app.LeftPanel, 'numeric');
            app.Cbrk1EditFieldLabel = uilabel(app.LeftPanel);
            app.Cbrk1EditFieldLabel.Text = 'Cbrk1';
            app.Cbrk1 = uieditfield(app.LeftPanel, 'numeric');
            app.Cbrk2EditFieldLabel = uilabel(app.LeftPanel);
            app.Cbrk2EditFieldLabel.Text = 'Cbrk2';
            app.Cbrk2 = uieditfield(app.LeftPanel, 'numeric');
            app.DIFFUSIONSPONGEEditField_2Label = uilabel(app.LeftPanel);
            app.DIFFUSIONSPONGEEditField_2Label.Text = {'DIFFUSION'; 'SPONGE'};
            app.DIFFUSIONSPONGE = uieditfield(app.LeftPanel, 'text');
            app.WAVEMAKEREditFieldLabel = uilabel(app.LeftPanel);
            app.WAVEMAKEREditFieldLabel.Text = {'WAVE'; 'MAKER'};
            app.WAVEMAKER = uieditfield(app.LeftPanel, 'text');
            app.T_INTVMEANEditFieldLabel = uilabel(app.LeftPanel);
            app.T_INTVMEANEditFieldLabel.Text = {'T_INTV'; 'MEAN'};
            app.T_INTVMEAN = uieditfield(app.LeftPanel, 'numeric');
            app.DerivedStatisticsLabel = uilabel(app.LeftPanel);
            app.DerivedStatisticsLabel.Text = 'Derived Statistics';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Time Series at Point')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, '\eta')
            zlabel(app.UIAxes, 'Z')
            app.BathyGraph = uiaxes(app.CenterPanel);
            title(app.BathyGraph, 'Associated Bathymetry')
            zlabel(app.BathyGraph, 'Z')
            app.SkasyGraph = uiaxes(app.CenterPanel);
            title(app.SkasyGraph, 'Skew and Asymmetry at Each Point in the Cross-Shore')
            zlabel(app.SkasyGraph, 'Z')
            app.ML_Plot = uiaxes(app.CenterPanel);
            title(app.ML_Plot, 'Skew/Asymmetry: Machine Learning vs. Actual')
            xlabel(app.ML_Plot, 'X')
            zlabel(app.ML_Plot, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FunwaveVariableParametersLabel = uilabel(app.RightPanel);
            app.FunwaveVariableParametersLabel.Text = 'Funwave Variable Parameters';
            app.PointtoPlotEditFieldLabel = uilabel(app.RightPanel);
            app.PointtoPlotEditFieldLabel.Text = 'Point to Plot';
            app.PointtoPlotEditField = uieditfield(app.RightPanel, 'numeric');
            app.PointtoPlotEditField.ValueChangedFcn = createCallbackFcn(app, @PointtoPlotEditFieldValueChanged, true);
            app.PointtoPlotEditField.Value = 900;
            app.SLPEditFieldLabel = uilabel(app.RightPanel);
            app.SLPEditFieldLabel.Text = 'SLP';
            app.SLPEditField = uieditfield(app.RightPanel, 'numeric');
            app.SLPEditField.ValueChangedFcn = createCallbackFcn(app, @SLPEditFieldValueChanged, true);
            app.SLPEditField.Value = 0.05;
            app.AMP_WKEditFieldLabel = uilabel(app.RightPanel);
            app.AMP_WKEditFieldLabel.Text = 'AMP_WK';
            app.AMP_WKEditField = uieditfield(app.RightPanel, 'numeric');
            app.AMP_WKEditField.ValueChangedFcn = createCallbackFcn(app, @AMP_WKEditFieldValueChanged, true);
            app.AMP_WKEditField.Value = 1;
            app.TPERIODEditFieldLabel = uilabel(app.RightPanel);
            app.TPERIODEditFieldLabel.Text = 'TPERIOD';
            app.TPERIODEditField = uieditfield(app.RightPanel, 'numeric');
            app.TPERIODEditField.ValueChangedFcn = createCallbackFcn(app, @TPERIODEditFieldValueChanged, true);
            app.TPERIODEditField.Value = 9;
            app.TrialBeingPlottedLabel = uilabel(app.RightPanel);
            app.TrialBeingPlottedLabel.Text = 'Trial Being Plotted';
            app.SkewEditFieldLabel = uilabel(app.RightPanel);
            app.SkewEditFieldLabel.Text = 'Skew';
            app.Skew = uieditfield(app.RightPanel, 'numeric');
            app.Skew.Value = 0.05;
            app.AsyLabel_2 = uilabel(app.RightPanel);
            app.AsyLabel_2.Text = 'Asy';
            app.Asy = uieditfield(app.RightPanel, 'numeric');
            app.Asy.Value = 0.05;
            app.IterationLabel = uilabel(app.RightPanel);
            app.IterationLabel.Text = 'Iteration #';
            app.Iteration_No = uieditfield(app.RightPanel, 'numeric');
            app.IterationLabel_2 = uilabel(app.RightPanel);
            app.IterationLabel_2.Text = 'SLP';
            app.Input_SLP = uieditfield(app.RightPanel, 'numeric');
            app.Input_SLP.Value = 0.05;
            app.IterationLabel_3 = uilabel(app.RightPanel);
            app.IterationLabel_3.Text = 'AMP_WK';
            app.Input_AMP_WK = uieditfield(app.RightPanel, 'numeric');
            app.Input_AMP_WK.Value = 0.05;
            app.IterationLabel_4 = uilabel(app.RightPanel);
            app.IterationLabel_4.Text = 'TPERIOD';
            app.Input_TPERIOD = uieditfield(app.RightPanel, 'numeric');
            app.Input_TPERIOD.Value = 0.05;
            app.IterationLabel_5 = uilabel(app.RightPanel);
            app.IterationLabel_5.Text = 'Xslp';
            app.Xslp_Box = uieditfield(app.RightPanel, 'numeric');
            app.TrueValuesPointLabel = uilabel(app.RightPanel);
            app.TrueValuesPointLabel.Text = 'True Values @ Point';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '________________';
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = '________________';
            app.SkewAsymmetryLabel = uilabel(app.RightPanel);
            app.SkewAsymmetryLabel.Text = 'Skew & Asymmetry';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewMLResults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end