classdef Plot_Time_Series_Point < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LoadinDataLabel                 matlab.ui.control.Label
        PointtoPlotEditField            matlab.ui.control.NumericEditField
        PointtoPlotEditFieldLabel       matlab.ui.control.Label
        LoadsumconstDataButton          matlab.ui.control.Button
        LoadsumvarsDataButton           matlab.ui.control.Button
        sumconstFileEditField           matlab.ui.control.EditField
        sumconstFileEditFieldLabel      matlab.ui.control.Label
        sumvarsFileEditField            matlab.ui.control.EditField
        sumvarsFileEditFieldLabel       matlab.ui.control.Label
        NumPyFileEditField              matlab.ui.control.EditField
        NumPyFileEditFieldLabel         matlab.ui.control.Label
        LoadNumPyDataButton             matlab.ui.control.Button
        FunwaveVariableParametersLabel  matlab.ui.control.Label
        TPERIODEditField                matlab.ui.control.NumericEditField
        TPERIODEditFieldLabel           matlab.ui.control.Label
        AMP_WKEditField                 matlab.ui.control.NumericEditField
        AMP_WKEditFieldLabel            matlab.ui.control.Label
        SLPEditField                    matlab.ui.control.NumericEditField
        SLPEditFieldLabel               matlab.ui.control.Label
        AnimateButton                   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        BathyGraph                      matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Data
        NPY 
        sumvars
        sumconst

        % Variables
        SLP
        AMPWK
        TPERIOD

        % Point in the model domain to plot
        point
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SLPEditField
        function SLPEditFieldValueChanged(app, event)
            value = app.SLPEditField.Value;
            app.SLP = value;
        end

        % Value changed function: AMP_WKEditField
        function AMP_WKEditFieldValueChanged(app, event)
            value = app.AMP_WKEditField.Value;
            app.AMPWK = value;
        end

        % Value changed function: TPERIODEditField
        function TPERIODEditFieldValueChanged(app, event)
            value = app.TPERIODEditField.Value;
            app.TPERIOD = value;
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)

            % Pull out sumvars table for ease
            sumvarsh = app.sumvars;
            sumconsth = app.sumconst; 

            % Find which trial best matches the input values
            distances = sqrt((sumvarsh.AMP_WK - app.AMPWK).^2 + ...
                 (sumvarsh.Tperiod - app.TPERIOD).^2 + ...
                 (sumvarsh.SLP - app.SLP).^2);
            [min_distance, ind] = min(distances);


            %%% Plot for Time Series
            % Plot the time series
            plot(app.UIAxes,squeeze(app.NPY(ind,:,app.point)))

            % Pull out the Funwave variables for labels
                SLP_s = num2str(app.sumvars{ind,'SLP'});
                AMP_WK_s = num2str(app.sumvars{ind,'AMP_WK'});
                Tperiod_s = num2str(app.sumvars{ind,'Tperiod'});
                Xslp_s = num2str(app.sumvars{ind,'Xslp'});
                titlename = {['SLP = ', SLP_s], ...
                    ['AMP_WK = ', AMP_WK_s],...
                    ['Tperiod = ', Tperiod_s],...
                    ['Xslp = ', Xslp_s]};
                title(app.UIAxes, titlename)

            % Plot Labels
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes, 'time (s)');
                ylabel(app.UIAxes, '\eta');

            
            %%% Plot for Bathymetry
            % Pull out relevant variables
            Xslp = app.sumvars{ind,'Xslp'};
            dep = -sumconsth.DEPTH_FLAT;
            Xc_WK = sumconsth.Xc_WK;
            Sp_W = sumconsth.Sponge_west_width;
            
            hold(app.BathyGraph,"on")
            plot(app.BathyGraph,[0,Xslp],[dep,dep],'Color','k','LineWidth',2)
            plot(app.BathyGraph,[Xslp,1024],[dep,0],'Color','k','LineWidth',2)
            scatter(app.BathyGraph,Xslp,dep, 'filled', 'o','MarkerEdgeColor','m','MarkerFaceColor','m');
            xline(app.BathyGraph,Sp_W,'Color','r','LineWidth',2, 'LineStyle','--');
            xline(app.BathyGraph,Xc_WK,'Color','b','LineWidth',2, 'LineStyle','--');
            grid(app.BathyGraph,'on')
            legend(app.BathyGraph,'','','Xslp','Sponge Layer', 'WaveMaker', 'Location','northwest');

        end

        % Button pushed function: LoadNumPyDataButton
        function LoadNumPyDataButtonPushed(app, event)
            [filename,path] = uigetfile();
            app.NPY = readNPY(fullfile(path,filename));
            app.NumPyFileEditField.Value = filename;

        end

        % Value changed function: NumPyFileEditField
        function NumPyFileEditFieldValueChanged(app, event)
            value = app.NumPyFileEditField.Value;
            
        end

        % Button pushed function: LoadsumvarsDataButton
        function LoadsumvarsDataButtonPushed(app, event)
            [filename,path] = uigetfile();
            app.sumvars = readtable(fullfile(path,filename));
            app.sumvarsFileEditField.Value = filename;
        end

        % Value changed function: PointtoPlotEditField
        function PointtoPlotEditFieldValueChanged(app, event)
            value = app.PointtoPlotEditField.Value;
            app.point = value;
        end

        % Value changed function: sumconstFileEditField
        function sumconstFileEditFieldValueChanged(app, event)
            value = app.sumconstFileEditField.Value;
            
        end

        % Button pushed function: LoadsumconstDataButton
        function LoadsumconstDataButtonPushed(app, event)
            [filename,path] = uigetfile();
            app.sumconst = readtable(fullfile(path,filename));
            app.sumconstFileEditField.Value = filename;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnimateButton = uibutton(app.LeftPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.SLPEditFieldLabel = uilabel(app.LeftPanel);
            app.SLPEditFieldLabel.Text = 'SLP';
            app.SLPEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SLPEditField.ValueChangedFcn = createCallbackFcn(app, @SLPEditFieldValueChanged, true);
            app.AMP_WKEditFieldLabel = uilabel(app.LeftPanel);
            app.AMP_WKEditFieldLabel.Text = 'AMP_WK';
            app.AMP_WKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AMP_WKEditField.ValueChangedFcn = createCallbackFcn(app, @AMP_WKEditFieldValueChanged, true);
            app.TPERIODEditFieldLabel = uilabel(app.LeftPanel);
            app.TPERIODEditFieldLabel.Text = 'TPERIOD';
            app.TPERIODEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TPERIODEditField.ValueChangedFcn = createCallbackFcn(app, @TPERIODEditFieldValueChanged, true);
            app.FunwaveVariableParametersLabel = uilabel(app.LeftPanel);
            app.FunwaveVariableParametersLabel.Text = 'Funwave Variable Parameters';
            app.LoadNumPyDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadNumPyDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNumPyDataButtonPushed, true);
            app.LoadNumPyDataButton.Text = 'Load NumPy Data';
            app.NumPyFileEditFieldLabel = uilabel(app.LeftPanel);
            app.NumPyFileEditFieldLabel.Text = 'NumPy File';
            app.NumPyFileEditField = uieditfield(app.LeftPanel, 'text');
            app.NumPyFileEditField.ValueChangedFcn = createCallbackFcn(app, @NumPyFileEditFieldValueChanged, true);
            app.sumvarsFileEditFieldLabel = uilabel(app.LeftPanel);
            app.sumvarsFileEditFieldLabel.Text = 'sumvars File';
            app.sumvarsFileEditField = uieditfield(app.LeftPanel, 'text');
            app.sumconstFileEditFieldLabel = uilabel(app.LeftPanel);
            app.sumconstFileEditFieldLabel.Text = 'sumconst File';
            app.sumconstFileEditField = uieditfield(app.LeftPanel, 'text');
            app.sumconstFileEditField.ValueChangedFcn = createCallbackFcn(app, @sumconstFileEditFieldValueChanged, true);
            app.LoadsumvarsDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadsumvarsDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsumvarsDataButtonPushed, true);
            app.LoadsumvarsDataButton.Text = 'Load sumvars Data';
            app.LoadsumconstDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadsumconstDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsumconstDataButtonPushed, true);
            app.LoadsumconstDataButton.Text = 'Load sumconst Data';
            app.PointtoPlotEditFieldLabel = uilabel(app.LeftPanel);
            app.PointtoPlotEditFieldLabel.Text = 'Point to Plot';
            app.PointtoPlotEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PointtoPlotEditField.ValueChangedFcn = createCallbackFcn(app, @PointtoPlotEditFieldValueChanged, true);
            app.LoadinDataLabel = uilabel(app.LeftPanel);
            app.LoadinDataLabel.Text = 'Load in Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BathyGraph = uiaxes(app.RightPanel);
            title(app.BathyGraph, 'Associated Bathymetry')
            xlabel(app.BathyGraph, 'X')
            ylabel(app.BathyGraph, 'Y')
            zlabel(app.BathyGraph, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_Time_Series_Point

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end