classdef US_GC_Bathy_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        County                          matlab.ui.control.EditField
        CountyLabel                     matlab.ui.control.Label
        State                           matlab.ui.control.EditField
        StateEditFieldLabel             matlab.ui.control.Label
        TownCity                        matlab.ui.control.EditField
        TownCityLabel                   matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        ProfileNumberEditField          matlab.ui.control.NumericEditField
        ProfileNumberEditFieldLabel     matlab.ui.control.Label
        LocationfromOpenStreetMapLabel  matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        ProfileLocationLabel            matlab.ui.control.Label
        FileSelectedEditField           matlab.ui.control.EditField
        FileSelectedEditFieldLabel      matlab.ui.control.Label
        LoadinDataLabel                 matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        IntAxes                         matlab.ui.control.UIAxes
        CutAxes                         matlab.ui.control.UIAxes
        RawAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        US_GC
        prof_num
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile();

            % Load in Data
            app.US_GC = load(fullfile(path,file));
            % Display File Name
            app.FileSelectedEditField.Value = fullfile(path,file);
        end

        % Value changed function: ProfileNumberEditField
        function ProfileNumberEditFieldValueChanged(app, event)
            value = app.ProfileNumberEditField.Value;
            app.prof_num = value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            data = app.US_GC.US_GC_Processed; %unpack data

            % Update Raw Data
            cla(app.RawAxes) 
            hold(app.RawAxes,"on")
            plot(app.RawAxes,data.Xshore_raw{app.prof_num},data.Elevation_raw{app.prof_num})
            grid(app.RawAxes,'on')

            % Update Cutoff Data
            cla(app.CutAxes) 
            hold(app.CutAxes,"on")
            plot(app.CutAxes,data.Xshore_cut{app.prof_num},data.Elevation_cut{app.prof_num})
            grid(app.CutAxes,'on')

            % Update Interpolated Data
            cla(app.IntAxes) 
            hold(app.IntAxes,"on")
            plot(app.IntAxes,data.Xshore_int(app.prof_num,:),data.Elevation_int(app.prof_num,:))
            grid(app.IntAxes,'on')

            % Update Latitude/Longitude
            lat = data.latlong(app.prof_num,1);
            lon = data.latlong(app.prof_num,2);
            app.LatitudeEditField.Value = lat;
            app.LongitudeEditField.Value = lon;

            % Update Location
            baseURL = 'https://nominatim.openstreetmap.org/reverse?';
            queryURL = [baseURL, 'format=json&lat=', num2str(lat), '&lon=', num2str(lon)];

            result = webread(queryURL);
            try
                nearestTown = result.address.city;
            
            catch
                try 
                    nearestTown = result.address.town;
                catch
                    try
                        nearestTown = result.address.village;
                    catch
                        nearestTown = 'N/A';
                    end

                
                end
            end

            try
                nearestCounty = result.address.county;
            catch
                nearestCounty = 'N/A';
            end


            try
                nearestState = result.address.state;
            catch
                nearestState = 'N/A';
            end


            app.TownCity.Value = nearestTown;
            app.State.Value = nearestState;
            app.County.Value = nearestCounty;


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadinDataLabel = uilabel(app.LeftPanel);
            app.LoadinDataLabel.Text = 'Load in Data';
            app.FileSelectedEditFieldLabel = uilabel(app.LeftPanel);
            app.FileSelectedEditFieldLabel.Text = 'File Selected';
            app.FileSelectedEditField = uieditfield(app.LeftPanel, 'text');
            app.ProfileLocationLabel = uilabel(app.LeftPanel);
            app.ProfileLocationLabel.Text = 'Profile Location';
            app.LatitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LongitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LocationfromOpenStreetMapLabel = uilabel(app.LeftPanel);
            app.LocationfromOpenStreetMapLabel.Text = 'Location from OpenStreetMap';
            app.ProfileNumberEditFieldLabel = uilabel(app.LeftPanel);
            app.ProfileNumberEditFieldLabel.Text = 'Profile Number';
            app.ProfileNumberEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ProfileNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ProfileNumberEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TownCityLabel = uilabel(app.LeftPanel);
            app.TownCityLabel.Text = 'Town/City';
            app.TownCity = uieditfield(app.LeftPanel, 'text');
            app.StateEditFieldLabel = uilabel(app.LeftPanel);
            app.StateEditFieldLabel.Text = 'State';
            app.State = uieditfield(app.LeftPanel, 'text');
            app.CountyLabel = uilabel(app.LeftPanel);
            app.CountyLabel.Text = 'County';
            app.County = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.RawAxes = uiaxes(app.RightPanel);
            title(app.RawAxes, 'Raw Profile as Given')
            xlabel(app.RawAxes, 'X')
            ylabel(app.RawAxes, 'Y')
            zlabel(app.RawAxes, 'Z')
            app.CutAxes = uiaxes(app.RightPanel);
            title(app.CutAxes, 'Profile Cutoff at Zero Crossing')
            xlabel(app.CutAxes, 'X')
            ylabel(app.CutAxes, 'Y')
            zlabel(app.CutAxes, 'Z')
            app.IntAxes = uiaxes(app.RightPanel);
            title(app.IntAxes, 'Profile Interpolated to 1024 Points')
            xlabel(app.IntAxes, 'X')
            ylabel(app.IntAxes, 'Y')
            zlabel(app.IntAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = US_GC_Bathy_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end