classdef Plot_D3_Field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        AnimateButton            matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        StartTime                matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel  matlab.ui.control.Label
        TrialNo                  matlab.ui.control.NumericEditField
        TrialNoEditFieldLabel    matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        D3c % Description
        tr=5
        st
        stop = false;
        curTime
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                work_dir = 'C:/Users/rschanta/ML-Funwave-Work/';
                D3c_path = 'Validation-Data/DUNE3_data/D3c';
                app.D3c = load(fullfile(work_dir,D3c_path));
                app.st = app.D3c.Trial05.t0;
                app.StartTime.Value = app.st;
                app.TrialNo.Value = 5;
        end

        % Value changed function: TrialNo
        function TrialNoValueChanged(app, event)
            value = app.TrialNo.Value;
            app.tr = value;
            app.st = app.D3c.(['Trial', sprintf('%02d', app.tr)]).t0;
            app.StartTime.Value = app.st;
        end

        % Value changed function: StartTime
        function StartTimeValueChanged(app, event)
            value = app.StartTime.Value;
            app.st = value;
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
        app.stop = false;
        cla(app.UIAxes);
        %% Plot profiles
            name = ['Trial', sprintf('%02d', app.tr)];
            WG = app.D3c.(name).WG;
            eta = app.D3c.(name).eta;
            % Find beginning times index
            t = app.D3c.(name).t;
            t0 = app.st;
            t_end = app.D3c.(name).t_end;
            [~, j_0] = min(abs(t0 -t));
            [~, j_end] = min(abs(t_end -t));


    % Plot first element of animation
        hold(app.UIAxes, "on")
        p = plot(app.UIAxes,WG,fliplr(eta(1,:))); 
        yyaxis(app.UIAxes, "right")
        g = plot(app.UIAxes,app.D3c.(name).Xb,app.D3c.(name).Yb,'LineWidth', 1.5,'Color',"#D95319",'LineStyle','-');
        ylim(app.UIAxes,[-4.5, 4.5])
        yyaxis(app.UIAxes,"left")
    % Set invariants
        ylim(app.UIAxes,[min(min(eta)), max(max(eta))]);
        xlabel(app.UIAxes,'Cross-shore'); ylabel(app.UIAxes,'\eta');
        title(app.UIAxes,'Default');
        grid(app.UIAxes,"on")
    % Align axes
        yyaxis(app.UIAxes,"right"); ylimr = get(app.UIAxes,'Ylim'); ratio = ylimr(1)/ylimr(2);
        yyaxis(app.UIAxes,"left"); yliml = get(app.UIAxes,'Ylim');
        if yliml(2)*ratio<yliml(1)
            set(app.UIAxes,'Ylim',[yliml(2)*ratio yliml(2)])
        else
            set(app.UIAxes,'Ylim',[yliml(1) yliml(1)/ratio])
        end

    % Plot each 
    iter = 0;
    for j = j_0:10:j_end
        if app.stop == false
            % Set XData and YData for plot
            set(p, 'YData', fliplr(eta(j,:)));
            if p.Color ~= 'b'
                set(p,'Color', 'b');
            end

    
            % Set Title of Plot
            if mod(iter, 10) == 0
                title_name = ['t = ', num2str(app.D3c.(name).t(j))];
                set(get(app.UIAxes,'Title'),'String',title_name);
                
            end
            iter = iter + 1;
            app.curTime = app.D3c.(name).t(j);
            drawnow; 


        end

    end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = ~app.stop;
            app.st = app.curTime;
            app.StartTime.Value  = app.st;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {294, 294};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TrialNoEditFieldLabel = uilabel(app.LeftPanel);
            app.TrialNoEditFieldLabel.Text = 'Trial No';
            app.TrialNo = uieditfield(app.LeftPanel, 'numeric');
            app.TrialNo.ValueChangedFcn = createCallbackFcn(app, @TrialNoValueChanged, true);
            app.StartTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTime = uieditfield(app.LeftPanel, 'numeric');
            app.StartTime.ValueChangedFcn = createCallbackFcn(app, @StartTimeValueChanged, true);
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AnimateButton = uibutton(app.LeftPanel, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Plot of eta field for Dune3 Data')
            xlabel(app.UIAxes, 'Cross-shore')
            ylabel(app.UIAxes, '\eta')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_D3_Field

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end