classdef Visualize_Field_Data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        h_taken                   matlab.ui.control.NumericEditField
        htakenLabel               matlab.ui.control.Label
        OffshoreConditionsforTrialLabel  matlab.ui.control.Label
        WavePeriod                matlab.ui.control.NumericEditField
        WavePeriodEditFieldLabel  matlab.ui.control.Label
        WaveHeight                matlab.ui.control.NumericEditField
        WaveHeightEditFieldLabel  matlab.ui.control.Label
        ErrorBox                  matlab.ui.control.EditField
        ErrorEditFieldLabel       matlab.ui.control.Label
        PlotBathymetryButton      matlab.ui.control.Button
        Trial_No                  matlab.ui.control.NumericEditField
        TrialEditFieldLabel       matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        DataLoading               matlab.ui.control.EditField
        RightPanel                matlab.ui.container.Panel
        ForFW                     matlab.ui.control.UIAxes
        sflip                     matlab.ui.control.UIAxes
        AsGiven                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        work_dir = 'C:/Users/rschanta/ML-Funwave-Work/'
        D3_dir = 'Validation-Data/DUNE3_data/' 
        data % The Dune3 Data Loaded in
        trial_no = 5% Trial Number to Plot
        Tp
        Hs
        h
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: AsGiven
        function AsGivenButtonDown(app, event)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.DataLoading.Value = 'Loading Finished4';
            app.data = load(fullfile(app.work_dir,app.D3_dir,'D3_struct.mat'));
            app.DataLoading.Value = 'Data Loaded';
        end

        % Button pushed function: PlotBathymetryButton
        function PlotBathymetryButtonPushed(app, event)
            try
                rd = app.data.(['Trial',sprintf('%02d', app.trial_no)]).raw_data;
                wc = app.data.(['Trial',sprintf('%02d', app.trial_no)]).wave_condition;

                % Plot bathymetry as given
                    % Initial Work
                        % Grab before and after profiles
                            X_before = rd.bed_before(:,1)'; X_after = rd.bed_after(:,1)';
                            Y_before = -rd.bed_before(:,2)'; Y_after = -rd.bed_after(:,2)';
                        % Define offshore height
                            h = wc.h;
                        % Find position of offshore conditions
                            [~, i_off] = min(abs(Y_before + wc.h));
                        % Get position of wave gauges
                            WG = rd.WG_loc_x;
                    
                    % Plot
                        cla(app.AsGiven)
                        hold(app.AsGiven,"on");
    
                        % Plot Before and After Profiles
                        plot(app.AsGiven,X_before,Y_before,'LineWidth',1.5,'Color','b','LineStyle','-')
                        plot(app.AsGiven,X_after,Y_after,'LineWidth',1.5,'Color','r','LineStyle','-')
                    
                        % Plot Position of Offshore Conditions
                        xline(app.AsGiven,X_before(i_off), 'LineStyle','-','Color','g','LineWidth',2)
                        
                        % Plot Wave Gauges
                        plot(app.AsGiven,[WG; WG], repmat(ylim(app.AsGiven)',1,size(WG,2)), 'Color',[0 0 0,0.5],'LineStyle','--','LineWidth',0.75);
                        
                        % Plot Properties
                        grid(app.AsGiven,'on');
                        legend(app.AsGiven,'Bed Before','Bed After','Offshore','Wave Gauges', 'Location','southwest');


                % Plot bathymetry shifted and flipped
                    % Initial Work
                        % Shift X coordinates to the left to the left
                            X_bef_sh = X_before - min(X_before);
                            X_aft_sh = X_after - min(X_after);
                            WG_sh = WG - min(X_before);
                            
                        % Flip Y coordinate directions as needed
                            % flip
                            Y_bef_fl = fliplr(Y_before);
                            Y_aft_fl = fliplr(Y_after);
                
                        % Find position of offshore Conditions
                        [~, i_off_fl] = min(abs(Y_bef_fl + wc.h));

                    % Plot
                        cla(app.sflip)
                        hold(app.sflip,"on");
            
                        % Plot Before and After Profiles
                        plot(app.sflip,X_bef_sh,Y_bef_fl,'LineWidth',1.5,'Color','b','LineStyle','-')
                        plot(app.sflip,X_aft_sh,Y_aft_fl,'LineWidth',1.5,'Color','r','LineStyle','-')
            
                        % Plot Position of Offshore Conditions
                        xline(app.sflip,X_bef_sh(i_off_fl), 'LineStyle','-','Color','g','LineWidth',2)
                        
                        % Plot Wave Gauges
                        plot(app.sflip,[WG_sh; WG_sh], repmat(ylim(app.sflip)',1,size(WG_sh,2)), 'Color',[0 0 0,0.5],'LineStyle','--','LineWidth',0.75);
                        
                        % Plot Propreties
                        grid(app.sflip,'on');
                        legend(app.sflip,'Bed Before','Bed After','Offshore', 'Wave Gauges', 'Location','northwest');
                
            % Plot Data Processed for 1024 sized FUNWAVE
                 % Initial Work
                        % Slice off entries After Offshore (AO) conditions
                        X_bef_AO = X_bef_sh(i_off_fl:end);
               
                        % Interpolate End (AO)
                        X_bef_AO_int = linspace(X_bef_AO(1),X_bef_AO(end),775);
                        Y_bef_AO_int = interp1(X_bef_sh,Y_bef_fl,X_bef_AO_int,"linear");
                        
                        % Interpolate Beginning Before Offshore Conditions (BO)
                            % Ensure that spacing is the same on each side
                                spacing = X_bef_AO_int(2)-X_bef_AO_int(1);
                        
                            % Generate X values
                            X_bef_BO_int = [];
                            for j = 1:249
                                new_x = X_bef_AO_int(1) - spacing*j;
                                X_bef_BO_int = [new_x, X_bef_BO_int];
                
                            end
            
                            % Generate Y values
                            Y_bef_BO_int = interp1(X_bef_sh,Y_bef_fl,X_bef_BO_int);
            
                        % Combine to form total series for FUNWAVE
                        X_FW = [X_bef_BO_int, X_bef_AO_int];
                        Y_FW = [Y_bef_BO_int, Y_bef_AO_int]; 
            
                        % Shift left
                        X_FW = X_FW - min(X_FW);
                        % Check offshore position 
                        [~, i_check] = min(abs(Y_FW + h));
            
                        % Place Sponge at 180
                        i_sponge = 180;
            
            % Plot
                cla(app.ForFW)
                hold(app.ForFW,"on");

                % Plot Input Profile
                plot(app.ForFW,X_FW,Y_FW,'LineWidth',1.5,'Color','b','LineStyle','-')
            
                % Plot Position of Offshore Conditions
                xline(app.ForFW,X_FW(i_check), 'LineStyle','-','Color','g','LineWidth',2)
            
                % Plot Position of Offshore Conditions
                xline(app.ForFW,X_FW(i_sponge), 'LineStyle','-','Color','r','LineWidth',2)
            
                % Plot Properties
                grid(app.ForFW,'on');
                legend(app.ForFW,'Bathymetry','Wavemaker','Sponge','Location','southeast');

            % Update Error Box
                app.ErrorBox.Value = '';
            catch
                app.ErrorBox.Value = 'Trial Not Found';
            end

        end

        % Value changed function: Trial_No
        function Trial_NoValueChanged(app, event)
            value = app.Trial_No.Value;
            app.trial_no = value;

            % Set offshore conditions
            wc = app.data.(['Trial',sprintf('%02d', app.trial_no)]).wave_condition;
            app.Tp = wc.Tp;
            app.WavePeriod.Value = wc.Tp;
            app.Hs = wc.Hs;
            app.WaveHeight.Value = wc.Hs;
            app.h = wc.h;
            app.h_taken.Value = wc.h;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {619, 619};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataLoading = uieditfield(app.LeftPanel, 'text');
            app.DataLoading.Value = 'Data Not Loaded';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TrialEditFieldLabel = uilabel(app.LeftPanel);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.Trial_No = uieditfield(app.LeftPanel, 'numeric');
            app.Trial_No.ValueChangedFcn = createCallbackFcn(app, @Trial_NoValueChanged, true);
            app.Trial_No.Value = 5;
            app.PlotBathymetryButton = uibutton(app.LeftPanel, 'push');
            app.PlotBathymetryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBathymetryButtonPushed, true);
            app.PlotBathymetryButton.Text = 'Plot Bathymetry';
            app.ErrorEditFieldLabel = uilabel(app.LeftPanel);
            app.ErrorEditFieldLabel.Text = 'Error ';
            app.ErrorBox = uieditfield(app.LeftPanel, 'text');
            app.WaveHeightEditFieldLabel = uilabel(app.LeftPanel);
            app.WaveHeightEditFieldLabel.Text = 'Wave Height';
            app.WaveHeight = uieditfield(app.LeftPanel, 'numeric');
            app.WavePeriodEditFieldLabel = uilabel(app.LeftPanel);
            app.WavePeriodEditFieldLabel.Text = 'Wave Period';
            app.WavePeriod = uieditfield(app.LeftPanel, 'numeric');
            app.OffshoreConditionsforTrialLabel = uilabel(app.LeftPanel);
            app.OffshoreConditionsforTrialLabel.Text = 'Offshore Conditions for Trial';
            app.htakenLabel = uilabel(app.LeftPanel);
            app.htakenLabel.Text = 'h taken';
            app.h_taken = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.AsGiven = uiaxes(app.RightPanel);
            title(app.AsGiven, 'Bathymetry as Given for Trial')
            xlabel(app.AsGiven, 'X')
            ylabel(app.AsGiven, 'Y')
            zlabel(app.AsGiven, 'Z')
            app.AsGiven.ButtonDownFcn = createCallbackFcn(app, @AsGivenButtonDown, true);
            app.sflip = uiaxes(app.RightPanel);
            title(app.sflip, 'Bathymetry Shifted and Flipped Left')
            xlabel(app.sflip, 'X')
            ylabel(app.sflip, 'Y')
            zlabel(app.sflip, 'Z')
            app.ForFW = uiaxes(app.RightPanel);
            title(app.ForFW, 'Bathymetry for FUNWAVE')
            xlabel(app.ForFW, 'X')
            ylabel(app.ForFW, 'Y')
            zlabel(app.ForFW, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_Field_Data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end